classdef TMDDApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TMDDsimUIFigure                matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ParentLamp                     matlab.ui.container.GridLayout
        GridLayout9                    matlab.ui.container.GridLayout
        AutomaticupdateCheckBox        matlab.ui.control.CheckBox
        ResetButton                    matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.Button
        AdjustparametersPanel          matlab.ui.container.Panel
        ReceptorelimkdegSlider         matlab.ui.control.Slider
        ReceptorelimkdegSliderLabel    matlab.ui.control.Label
        DrugeliminationkelSlider       matlab.ui.control.Slider
        DrugeliminationkelLabel        matlab.ui.control.Label
        BindingconstantkonSlider       matlab.ui.control.Slider
        BindingconstantkonSliderLabel  matlab.ui.control.Label
        SimulationsetupPanel           matlab.ui.container.Panel
        DosingAmountField              matlab.ui.control.NumericEditField
        DosingAmountSlider             matlab.ui.control.Slider
        DosingamountSliderLabel        matlab.ui.control.Label
        DosingIntervalField            matlab.ui.control.Spinner
        DosingintervalSpinnerLabel     matlab.ui.control.Label
        DurationofsimulationField      matlab.ui.control.NumericEditField
        SimulationstoptimeLabel        matlab.ui.control.Label
        DurationofsimulationUnitField  matlab.ui.control.Label
        DosingAmountUnitField          matlab.ui.control.Label
        DosingIntervalUnitField        matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ParentNCA                      matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        TargetoccupancyTab             matlab.ui.container.Tab
        ParentRO                       matlab.ui.container.GridLayout
        ConcentrationsTab              matlab.ui.container.Tab
        ParentConc                     matlab.ui.container.GridLayout
        ModeldiagramTab                matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties ( SetAccess=private, GetAccess={?tTMDDApp} )
        Model 
        ConcViewObj
        ROViewObj
        LampViewObj
        NCAViewObj
    end

    methods (Access = public)
    
        function updateApp(app)
            % retrieve simulation settings from App
            kel = app.DrugeliminationkelSlider.Value;
            kon = app.BindingconstantkonSlider.Value;
            kdeg = app.ReceptorelimkdegSlider.Value;
            stopTime = app.DurationofsimulationField.Value;
            
            amount = app.DosingAmountField.Value;
            interval = app.DosingIntervalField.Value;
            
            parameters = [kel, kon, kdeg,stopTime, amount, interval];

            % simulate model using SimFunction
            try
                simulate(app.Model, parameters);
            catch ME
                uialert(app.TMDDsimUIFigure,ME.message,ME.identifier);
            end
        end
        
        function setDefaultValues(app)
            app.BindingconstantkonSlider.Value = app.Model.Kon0;
            app.DrugeliminationkelSlider.Value = app.Model.Kel0;
            
            app.DurationofsimulationField.Value = app.Model.Duration0;
            app.DosingAmountField.Value = app.Model.Amount0;
            app.DosingAmountSlider.Value = app.Model.Amount0;
            app.DosingIntervalField.Value = app.Model.Interval0;
        end
        
    end
    
    methods (Access = private)
        
        function fliplightness(app)
            app.TMDDsimUIFigure.Color = fliplightness(app.TMDDsimUIFigure.Color);
            app.LeftPanel.BackgroundColor = fliplightness(app.LeftPanel.BackgroundColor);
            app.RightPanel.BackgroundColor = fliplightness(app.RightPanel.BackgroundColor);
            app.GridLayout.BackgroundColor = fliplightness(app.GridLayout.BackgroundColor);
            app.GridLayout2.BackgroundColor = fliplightness(app.GridLayout2.BackgroundColor);
            app.GridLayout9.BackgroundColor = fliplightness(app.GridLayout9.BackgroundColor);
            app.GridLayout3.BackgroundColor = fliplightness(app.GridLayout3.BackgroundColor);
            app.ParentLamp.BackgroundColor = fliplightness(app.ParentLamp.BackgroundColor);
            app.ParentNCA.BackgroundColor = fliplightness(app.ParentNCA.BackgroundColor);
            app.NCAViewObj.Color = fliplightness(app.NCAViewObj.Color);
            app.ParentRO.BackgroundColor = fliplightness(app.ParentRO.BackgroundColor);
            app.ParentConc.BackgroundColor = fliplightness(app.ParentConc.BackgroundColor);
            app.AdjustparametersPanel.BackgroundColor = fliplightness(app.AdjustparametersPanel.BackgroundColor);
            app.SimulationsetupPanel.BackgroundColor = fliplightness(app.SimulationsetupPanel.BackgroundColor);
            app.AutomaticupdateCheckBox.FontColor = fliplightness(app.AutomaticupdateCheckBox.FontColor);
            app.SimulateButton.BackgroundColor = fliplightness(app.SimulateButton.BackgroundColor);
            app.ROViewObj.Axes.ColorOrder = fliplightness(app.ROViewObj.Axes.ColorOrder);
            app.ConcViewObj.Axes.ColorOrder = fliplightness(app.ConcViewObj.Axes.ColorOrder);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % create progress dialog
            d = uiprogressdlg(app.TMDDsimUIFigure,'Message','Preparing App ...','Indeterminate','on');
           
            % instantiate components
            app.Model       = SimulationModel();
            app.ConcViewObj = ConcTimecourseView(app.ParentConc, app.Model);
            app.ROViewObj   = ROTimecourseView(app.ParentRO, app.Model);
            app.LampViewObj = LampView(app.ParentLamp, app.Model);
            app.NCAViewObj  = NCAView(app.ParentNCA, app.Model);

            % adapt colors according to theme
            if app.TMDDsimUIFigure.Theme.BaseColorStyle == "dark"
                fliplightness(app);
            end
            app.TMDDsimUIFigure.ThemeChangedFcn = @(~,~) fliplightness(app);

            % update UI with model values
            setDefaultValues(app);

            % run simulation and update app
            updateApp(app);

            % close progress bar
            d.Value = 1;
            close(d);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TMDDsimUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {637, 637};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {426, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: BindingconstantkonSlider
        function BindingconstantkonSliderValueChanged(app, event)
            if app.AutomaticupdateCheckBox.Value
                updateApp(app);
            end
        end

        % Value changed function: DosingIntervalField
        function DosingIntervalFieldValueChanged(app, event)
            if app.AutomaticupdateCheckBox.Value
                updateApp(app);
            end
        end

        % Value changed function: DurationofsimulationField
        function DurationofsimulationFieldValueChanged(app, event)
            if app.AutomaticupdateCheckBox.Value
                updateApp(app);
            end
        end

        % Value changed function: DrugeliminationkelSlider
        function DrugeliminationkelSliderValueChanged(app, event)
            if app.AutomaticupdateCheckBox.Value
                updateApp(app);
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            updateApp(app);
        end

        % Value changed function: DosingAmountSlider
        function DosingAmountSliderValueChanged(app, event)
            app.DosingAmountField.Value = app.DosingAmountSlider.Value;
            if app.AutomaticupdateCheckBox.Value
                updateApp(app);
            end
        end

        % Value changed function: DosingAmountField
        function DosingAmountFieldValueChanged(app, event)
            app.DosingAmountSlider.Value = app.DosingAmountField.Value;
            if app.AutomaticupdateCheckBox.Value
                updateApp(app);
            end
        end

        % Value changing function: DosingAmountSlider
        function DosingAmountSliderValueChanging(app, event)
            app.DosingAmountField.Value = round(event.Value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            setDefaultValues(app);
            updateApp(app);
        end

        % Value changed function: ReceptorelimkdegSlider
        function ReceptorelimkdegSliderValueChanged(app, event)
            if app.AutomaticupdateCheckBox.Value
                updateApp(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TMDDsimUIFigure = uifigure('Visible', 'off');
            app.TMDDsimUIFigure.Name = 'TMDDsim';
            app.TMDDsimUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TMDDsimUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SimulationsetupPanel = uipanel(app.GridLayout2);
            app.SimulationsetupPanel.Title = 'Simulation setup';
            app.DosingIntervalUnitField = uilabel(app.SimulationsetupPanel);
            app.DosingIntervalUnitField.Text = 'hours';
            app.DosingAmountUnitField = uilabel(app.SimulationsetupPanel);
            app.DosingAmountUnitField.Text = 'nanomole';
            app.DurationofsimulationUnitField = uilabel(app.SimulationsetupPanel);
            app.DurationofsimulationUnitField.Text = 'hours';
            app.SimulationstoptimeLabel = uilabel(app.SimulationsetupPanel);
            app.SimulationstoptimeLabel.Text = 'Simulation stop time';
            app.DurationofsimulationField = uieditfield(app.SimulationsetupPanel, 'numeric');
            app.DurationofsimulationField.UpperLimitInclusive = 'off';
            app.DurationofsimulationField.Limits = [1 Inf];
            app.DurationofsimulationField.ValueChangedFcn = createCallbackFcn(app, @DurationofsimulationFieldValueChanged, true);
            app.DurationofsimulationField.Value = 119;
            app.DosingintervalSpinnerLabel = uilabel(app.SimulationsetupPanel);
            app.DosingintervalSpinnerLabel.Text = 'Dosing interval';
            app.DosingIntervalField = uispinner(app.SimulationsetupPanel);
            app.DosingIntervalField.Step = 4;
            app.DosingIntervalField.Limits = [4 168];
            app.DosingIntervalField.RoundFractionalValues = 'on';
            app.DosingIntervalField.ValueChangedFcn = createCallbackFcn(app, @DosingIntervalFieldValueChanged, true);
            app.DosingIntervalField.Value = 24;
            app.DosingamountSliderLabel = uilabel(app.SimulationsetupPanel);
            app.DosingamountSliderLabel.Text = 'Dosing amount';
            app.DosingAmountSlider = uislider(app.SimulationsetupPanel);
            app.DosingAmountSlider.Limits = [0 300];
            app.DosingAmountSlider.MajorTicks = [0 100 200 300];
            app.DosingAmountSlider.ValueChangedFcn = createCallbackFcn(app, @DosingAmountSliderValueChanged, true);
            app.DosingAmountSlider.ValueChangingFcn = createCallbackFcn(app, @DosingAmountSliderValueChanging, true);
            app.DosingAmountSlider.Value = 150;
            app.DosingAmountField = uieditfield(app.SimulationsetupPanel, 'numeric');
            app.DosingAmountField.Limits = [0 300];
            app.DosingAmountField.RoundFractionalValues = 'on';
            app.DosingAmountField.ValueChangedFcn = createCallbackFcn(app, @DosingAmountFieldValueChanged, true);
            app.DosingAmountField.Value = 150;
            app.AdjustparametersPanel = uipanel(app.GridLayout2);
            app.AdjustparametersPanel.Title = 'Adjust parameters';
            app.BindingconstantkonSliderLabel = uilabel(app.AdjustparametersPanel);
            app.BindingconstantkonSliderLabel.Text = {'Binding constant'; '(kon)'};
            app.BindingconstantkonSlider = uislider(app.AdjustparametersPanel);
            app.BindingconstantkonSlider.Limits = [0 0.5];
            app.BindingconstantkonSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5];
            app.BindingconstantkonSlider.ValueChangedFcn = createCallbackFcn(app, @BindingconstantkonSliderValueChanged, true);
            app.DrugeliminationkelLabel = uilabel(app.AdjustparametersPanel);
            app.DrugeliminationkelLabel.Text = {'Drug elimination'; '(kel)'};
            app.DrugeliminationkelSlider = uislider(app.AdjustparametersPanel);
            app.DrugeliminationkelSlider.Limits = [0 2];
            app.DrugeliminationkelSlider.MajorTicks = [0 0.4 0.8 1.2 1.6 2];
            app.DrugeliminationkelSlider.ValueChangedFcn = createCallbackFcn(app, @DrugeliminationkelSliderValueChanged, true);
            app.ReceptorelimkdegSliderLabel = uilabel(app.AdjustparametersPanel);
            app.ReceptorelimkdegSliderLabel.Text = {'Receptor elim.'; '(kdeg)'};
            app.ReceptorelimkdegSlider = uislider(app.AdjustparametersPanel);
            app.ReceptorelimkdegSlider.Limits = [0.01 0.5];
            app.ReceptorelimkdegSlider.ValueChangedFcn = createCallbackFcn(app, @ReceptorelimkdegSliderValueChanged, true);
            app.ReceptorelimkdegSlider.Value = 0.1;
            app.SimulateButton = uibutton(app.GridLayout2, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Icon = fullfile(pathToMLAPP, 'images', 'Run_24.png');
            app.SimulateButton.Text = 'Simulate';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.AutomaticupdateCheckBox = uicheckbox(app.GridLayout9);
            app.AutomaticupdateCheckBox.Text = 'Automatic update';
            app.AutomaticupdateCheckBox.Value = true;
            app.ParentLamp = uigridlayout(app.GridLayout2);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.TargetoccupancyTab = uitab(app.TabGroup);
            app.TargetoccupancyTab.Title = 'Target occupancy';
            app.ParentRO = uigridlayout(app.TargetoccupancyTab);
            app.ConcentrationsTab = uitab(app.TabGroup);
            app.ConcentrationsTab.Title = 'Concentrations';
            app.ParentConc = uigridlayout(app.ConcentrationsTab);
            app.ModeldiagramTab = uitab(app.TabGroup);
            app.ModeldiagramTab.Title = 'Model diagram';
            app.GridLayout6 = uigridlayout(app.ModeldiagramTab);
            app.Image = uiimage(app.GridLayout6);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'diagram.svg');
            app.ParentNCA = uigridlayout(app.GridLayout3);
            % Show the figure after all components are created
            app.TMDDsimUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TMDDApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TMDDsimUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TMDDsimUIFigure)
        end
    end
end